classdef AIDataPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIAnalysisUIFigure    matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        PlotsTab              matlab.ui.container.Tab
        ControlsPanel         matlab.ui.container.Panel
        LoadButton            matlab.ui.control.Button
        LogTransformCheckBox  matlab.ui.control.CheckBox
        PFDropDownLabel       matlab.ui.control.Label
        PFDropDown            matlab.ui.control.DropDown
        AFCCheckBox           matlab.ui.control.CheckBox
        ReplotButton          matlab.ui.control.Button
        plotAxis1             matlab.ui.control.UIAxes
        plotAxis2             matlab.ui.control.UIAxes
        plotAxis3             matlab.ui.control.UIAxes
        plotAxis4             matlab.ui.control.UIAxes
        MetadataTab           matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end


    properties (Access = public)
        PF
        logT
        ana % main metadata
        scB
        scW
        response
        responseInfo
        task
        taskB
        taskW
    end

    methods (Access = private)
    
        function results = LoadData(app)
        
            app.PF = eval(['@PAL_' app.PFDropDown.Value]);
            app.logT = logical(app.LogTransformCheckBox.Value);
            
            [file,path]=uigetfile('*.mat','Load AI data file');
            if isnumeric(file)
                return
            end
            filepath = fullfile(path,file);
            load(filepath)
            
            app.ana = ana;
            app.scW = staircaseW;
            app.scB = staircaseB;
            app.response = response;
            app.responseInfo = responseInfo;
            plot(app);
        end
    
        function reset(app)
            app.ana = [];
            app.scB = [];
            app.scW = [];
            app.response = [];
            app.responseInfo = [];
            app.task = [];
            app.taskB = [];
            app.taskW = [];
        end
    
        function plot(app)
            if isempty(app.response);return;end
            x = 1:length(app.response);
            info = cell2mat(app.responseInfo);
            ped = [info.pedestal];
            
            idxW = [info.contrastOut] == 1;
            idxB = [info.contrastOut] == 0;
            if app.AFCCheckBox.Value == 1
                idxNO = app.response == app.ana.values.NOSEE;
                idxYESBRIGHT = app.response == app.ana.values.YESBRIGHT;
                idxYESDARK = app.response == app.ana.values.YESDARK;
                cla(app.plotAxis1); line(app.plotAxis1,[0 max(x)+1],[0.5 0.5],'LineStyle','--','LineWidth',2); hold(app.plotAxis1,'on')
                plot(app.plotAxis1, x(idxNO & idxB), ped(idxNO & idxB),'ro','MarkerFaceColor','r','MarkerSize',8);
                plot(app.plotAxis1, x(idxNO & idxW), ped(idxNO & idxW),'bo','MarkerFaceColor','b','MarkerSize',8);
                plot(app.plotAxis1, x(idxYESDARK & idxB), ped(idxYESDARK & idxB),'rv','MarkerFaceColor','w','MarkerSize',8);
                plot(app.plotAxis1, x(idxYESDARK & idxW), ped(idxYESDARK & idxW),'bv','MarkerFaceColor','w','MarkerSize',8);
                plot(app.plotAxis1, x(idxYESBRIGHT & idxB), ped(idxYESBRIGHT & idxB),'r^','MarkerFaceColor','w','MarkerSize',8);
                plot(app.plotAxis1, x(idxYESBRIGHT & idxW), ped(idxYESBRIGHT & idxW),'b^','MarkerFaceColor','w','MarkerSize',8);
            else
            	idxNO = app.response == app.ana.values.NOSEE;
		idxYESSEE = app.response == app.ana.values.YESSEE;
		cla(app.plotAxis1); line(app.plotAxis1,[0 max(x)+1],[0.5 0.5],'LineStyle','--','LineWidth',2); hold(app.plotAxis1,'on')
		plot(app.plotAxis1,x(idxNO & idxB), ped(idxNO & idxB),'ro','MarkerFaceColor','r','MarkerSize',8);
		plot(app.plotAxis1,x(idxNO & idxW), ped(idxNO & idxW),'bo','MarkerFaceColor','b','MarkerSize',8);
		plot(app.plotAxis1,x(idxYESSEE & idxB), ped(idxYESSEE & idxB),'rv','MarkerFaceColor','w','MarkerSize',8);
		plot(app.plotAxis1,x(idxYESSEE & idxW), ped(idxYESSEE & idxW),'bv','MarkerFaceColor','w','MarkerSize',8);
            end
          
            title(app.plotAxis1, sprintf('Raw Response Data for %s',app.ana.nameExp));
            box(app.plotAxis1,'on'); grid(app.plotAxis1,'on');
            ylim(app.plotAxis1,[0 1]);
            xlim(app.plotAxis1,[0 max(x)+1]);
            xlabel(app.plotAxis1,'Trials (red=BLACK blue=WHITE)')
            ylabel(app.plotAxis1,'Pedestal Contrast')
            hold(app.plotAxis1,'off')
            scaleM = 200;
            tit = ''
            if app.ana.useStaircase == true
                cla(app.plotAxis2); hold(app.plotAxis2,'on');
                if ~isempty(app.scB.threshold)
                    rB = linspace(min(app.scB.stimRange),max(app.scW.stimRange),100);
                    outB = app.PF([app.scB.threshold(end) ...
                    	app.scB.slope(end) app.scB.guess(end) ...
                    	app.scB.lapse(end)], rB);
                    plot(app.plotAxis2,rB,outB,'r-','LineWidth',2);
                    
                    r = app.scB.response;
                    t = app.scB.x(1:length(r));
                    yes = r == 1;
                    no = r == 0;
                    plot(app.plotAxis2,t(yes), ones(1,sum(yes)),'ro','MarkerFaceColor','r','MarkerSize',3);
                    plot(app.plotAxis2,t(no), zeros(1,sum(no)),'ro','MarkerFaceColor','w','MarkerSize',3);
                    
                    [SL, NP, OON] = PAL_PFML_GroupTrialsbyX(app.scB.x(1:length(app.scB.response)),app.scB.response,ones(size(app.scB.response)));
                    for SR = 1:length(SL(OON~=0))
                        scatter(app.plotAxis2, SL(SR), NP(SR)/OON(SR), scaleM*sqrt(OON(SR)./sum(OON)), ...
                            'MarkerFaceColor',[1 0.7 0.7],'MarkerEdgeColor','k','MarkerFaceAlpha',.7)
                    end
                    tit = sprintf('B\\alpha:%.2g \\pm %.2g | B\\beta:%.2g \\pm %.2g',app.scB.threshold(end),app.scB.seThreshold(end),app.scB.slope(end),app.scB.seSlope(end));
                end
                if ~isempty(app.scW.threshold)
                    rW = linspace(min(app.scW.stimRange), max(app.scW.stimRange), 100);
                    outW = app.PF([app.scW.threshold(end) ...
                    	app.scW.slope(end) app.scW.guess(end) ...
                    	app.scW.lapse(end)], rW);
                    plot(app.plotAxis2,rW,outW,'b--','LineWidth',2);
                    
                    r = app.scW.response;
                    t = app.scW.x(1:length(r));
                    yes = r == 1;
                    no = r == 0;
                    plot(app.plotAxis2,t(yes), ones(1,sum(yes))-0.01,'bo','MarkerFaceColor','b','MarkerSize',3);
                    plot(app.plotAxis2,t(no), zeros(1,sum(no))+0.01,'bo','MarkerFaceColor','w','MarkerSize',3);
                    
                    [SL, NP, OON] = PAL_PFML_GroupTrialsbyX(app.scW.x(1:length(app.scW.response)),app.scW.response,ones(size(app.scW.response)));
                    for SR = 1:length(SL(OON~=0))
                        scatter(app.plotAxis2, SL(SR), NP(SR)/OON(SR)-0.005, scaleM*sqrt(OON(SR)./sum(OON)), ...
                            'MarkerFaceColor',[0.7 0.7 1],'MarkerEdgeColor','b','MarkerFaceAlpha',.7, 'MarkerEdgeAlpha',.3)
                    end
                    tit = [tit sprintf(' | W\\alpha:%.2g \\pm %.2g | W\\beta:%.2g \\pm %.2g',app.scW.threshold(end),app.scW.seThreshold(end),app.scW.slope(end),app.scW.seSlope(end))];
                end
                box(app.plotAxis2, 'on'); grid(app.plotAxis2, 'on');
                ylim(app.plotAxis2, [0 1]);
                xlim(app.plotAxis2, [0 max([max(app.scB.stimRange) max(app.scW.stimRange)])]);
                xlabel(app.plotAxis2, 'Luminance (red=BLACK blue=WHITE)');
                ylabel(app.plotAxis2, 'Responses');
                title(app.plotAxis2,tit);
                hold(app.plotAxis2, 'off');
                
                cla(app.plotAxis3); 
                pos = PAL_Scale0to1(app.scB.pdf(:,:,1,1));
                imagesc(app.plotAxis3, app.scB.priorBetaRange, app.scB.priorAlphaRange, pos);
                axis(app.plotAxis3,'tight');
                xlabel(app.plotAxis3, 'Beta \beta');
                ylabel(app.plotAxis3, 'Alpha \alpha');
                title(app.plotAxis3, 'Black Posterior');
                
                cla(app.plotAxis4); 
                pos = PAL_Scale0to1(app.scW.pdf(:,:,1,1));
                imagesc(app.plotAxis4, app.scW.priorBetaRange, app.scW.priorAlphaRange, pos);
                axis(app.plotAxis4,'tight');
                xlabel(app.plotAxis4, 'Beta \beta');
                ylabel(app.plotAxis4, 'Alpha \alpha');
                title(app.plotAxis4, 'White Posterior');
            end
            drawnow;
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadData();
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            app.PF = eval(['@PAL_' app.PFDropDown.Value]);
            app.logT = logical(app.LogTransformCheckBox.Value);
            if isempty(app.ana); return; end
            plot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AIAnalysisUIFigure = uifigure;
            app.AIAnalysisUIFigure.Name = 'AIAnalysis';
            app.TabGroup = uitabgroup(app.AIAnalysisUIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.ControlsPanel = uipanel(app.PlotsTab);
            app.ControlsPanel.Title = 'Controls';
            app.LoadButton = uibutton(app.ControlsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LogTransformCheckBox = uicheckbox(app.ControlsPanel);
            app.LogTransformCheckBox.Text = 'Log Transform?';
            app.PFDropDownLabel = uilabel(app.ControlsPanel);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.ControlsPanel);
            app.PFDropDown.Items = {'Weibull', 'Quick', 'Gumbel', 'logQuick', 'Logistic', 'CumulativeNormal', 'HyperbolicSecant'};
            app.PFDropDown.Value = 'Weibull';
            app.AFCCheckBox = uicheckbox(app.ControlsPanel);
            app.AFCCheckBox.Text = '3AFC?';
            app.AFCCheckBox.Value = true;
            app.ReplotButton = uibutton(app.ControlsPanel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.plotAxis1 = uiaxes(app.PlotsTab);
            title(app.plotAxis1, 'Raw Data')
            xlabel(app.plotAxis1, 'Trial')
            ylabel(app.plotAxis1, 'Stimulus')
            app.plotAxis2 = uiaxes(app.PlotsTab);
            title(app.plotAxis2, 'PF')
            xlabel(app.plotAxis2, 'Stimulus')
            ylabel(app.plotAxis2, 'Choice Probability')
            app.plotAxis3 = uiaxes(app.PlotsTab);
            title(app.plotAxis3, 'Posterior')
            xlabel(app.plotAxis3, 'beta')
            ylabel(app.plotAxis3, 'alpha')
            app.plotAxis4 = uiaxes(app.PlotsTab);
            title(app.plotAxis4, 'Posterior')
            xlabel(app.plotAxis4, 'beta')
            ylabel(app.plotAxis4, 'alpha')
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.UITable = uitable(app.MetadataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = AIDataPlot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AIAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIAnalysisUIFigure)
        end
    end
end