classdef masked < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Afterimage Contrast
        Panel                 matlab.ui.container.Panel          % Settings
        UIAxes                matlab.ui.control.UIAxes           % Psychophysical Res...
        LabelSlider           matlab.ui.control.Label            % Fix Window
        Slider                matlab.ui.control.Slider           % [0 5]
        LabelNumericEditField matlab.ui.control.Label            % Mask Time
        NumericEditField      matlab.ui.control.NumericEditField % [0 5]
        Label                 matlab.ui.control.Label            % Mask Time
        NumericEditField2     matlab.ui.control.NumericEditField % [0 5]
        LabelSwitch           matlab.ui.control.Label            % Eyelink?
        Switch                matlab.ui.control.Switch           % Off, On
        LabelLamp             matlab.ui.control.Label            % ?
        Lamp                  matlab.ui.control.Lamp            
    end


    properties (Access = public)
        useEyeLink = true % Description
    end
 
 methods (Access = private)
  
  function results = maskedafterimage(app)
   %------------Experiment settings
   app.useEyeLink = true;
   isDummy = true;
   useStaircase = false;
   backgroundColour = [0.6863 0.6863 0.6863];
   stimTime = 4;
   maskTime = 0.3;
   maskOffsetTime = 1;
   subject = 'Ian';
   fixx = 0;
   fixy = 0;
   pixelsPerCm = 44; %26 for Dorris lab,32=Lab CRT -- 44=27"monitor or Macbook Pro
   nBlocks = 160; %number of repeated blocks?
   windowed = [1024 768];
   eyelinkIP = [];
   pedestalBlack = [0.4275    0.4784    0.5216    0.5647    0.6000    0.6353    0.6667    0.6863];
   pedestalWhite = [0.6863    0.7216    0.7490    0.7765    0.8000    0.8275    0.8510    0.8745];
   %-------eyetracker settings
   fixX = 0;
   fixY = 0;
   firstFixInit = 1;
   firstFixTime = 0.7;
   firstFixRadius = 1.5;
   strictFixation = true;
   %-------------------------
   
   if useStaircase; type = 'STAIR'; else type = 'MOC'; end %#ok<*UNRCH>
   name = ['AI_' type '_' subject];
   c = sprintf(' %i',fix(clock()));
   c = regexprep(c,' ','_');
   name = [name c];
   
   %-----stimulus
   st = spotStimulus();
   st.name = ['STIM_' name];
   st.xPosition = -3;
   st.contrast = 1;
   st.size = 2;
   
   %-----test stimulus
   t = spotStimulus();
   t.name = ['TEST_' name];
   t.xPosition = -3;
   t.contrast = 1;
   t.size = 2;
   
   %-----mask stimulus
   m = gratingStimulus();
   m.contrast = 0.2;
   m.colour = 0.6863;
   m.name = ['MASK_' name];
   m.xPosition = -3;
   m.size = 2.25;
   m.sigma = 11;
   
   %-----combine them into a single meta stimulus
   stimuli = metaStimulus();
   stimuli.name = name;
   
   sidx = 1;
   maskidx = 1;
   stimuli{sidx} = st;
   stimuli.maskStimuli{maskidx} = m;
   stimuli.showMask = false;
   
   %-----open the PTB screens
   s = screenManager('verbose',false,'blend',true,'screen',1,'pixelsPerCm',pixelsPerCm,...
   	'bitDepth','8bit','debug',true,'antiAlias',0,'nativeBeamPosition',0, ...
   	'srcMode','GL_SRC_ALPHA','dstMode','GL_ONE_MINUS_SRC_ALPHA',...
   	'windowed',windowed,'backgroundColour',[backgroundColour 0]); %use a temporary screenManager object
   screenVals = open(s); %open PTB screen
   setup(stimuli,s); %setup our stimulus object
   setup(t,s) % setup our test probe stimulus
   
   %-----setup eyelink
   if useEyeLink == true;
   	eL = eyelinkManager('IP',eyelinkIP);
   	%eL.verbose = true;
   	eL.isDummy = isDummy; %use dummy or real eyelink?
   	eL.name = name;
   	eL.recordData = false; %save EDF file
   	eL.sampleRate = 250;
   	eL.remoteCalibration = false; % manual calibration?
   	eL.calibrationStyle = 'HV9'; % calibration style
   	eL.modify.calibrationtargetcolour = [1 1 0];
   	eL.modify.calibrationtargetsize = 0.5;
   	eL.modify.calibrationtargetwidth = 0.01;
   	eL.modify.waitformodereadytime = 500;
   	eL.modify.devicenumber = -1; % -1 = use any keyboard
   	% X, Y, FixInitTime, FixTime, Radius, StrictFix
   	updateFixationValues(eL, fixX, fixY, firstFixInit, firstFixTime, firstFixRadius, strictFixation);
   	initialise(eL, s);
   	setup(eL);
   end
   
   task = stimulusSequence();
   task.name = name;
   task.nBlocks = 20;
   task.nVar(1).name = 'colour';
   task.nVar(1).stimulus = [1];
   task.nVar(1).values = [0 1];
   randomiseStimuli(task);
   initialiseTask(task);
   
   taskW = stimulusSequence();
   taskW.name = name;
   taskW.nBlocks = 10;
   taskW.nVar(1).name = 'pedestalWhite';
   taskW.nVar(1).stimulus = [1];
   taskW.nVar(1).values = pedestalWhite;
   randomiseStimuli(taskW);
   initialiseTask(taskW);
   
   taskB = stimulusSequence();
   taskB.name = name;
   taskB.nBlocks = 10;
   taskB.nVar(1).name = 'pedestalBlack';
   taskB.nVar(1).stimulus = [1];
   taskB.nVar(1).values = pedestalBlack;
   randomiseStimuli(taskB);
   initialiseTask(taskB);
   
   try %our main experimentqal try catch loop
    
   	if useEyeLink == true; getSample(eL); end
   	
   	loop = 1;
   	breakloop = false;
   	
   	while ~breakloop
   		%-----setup our values and print some info for the trial
   		hide(stimuli);
   		stimuli.showMask = false;
   		colourOut = task.outValues{task.totalRuns,1};
   		stimuli{1}.colourOut = colourOut;
   		stimuli.update();
   		if colourOut == 0
   			pedestal = taskB.outValues{taskB.totalRuns,1};
   		else
   			pedestal = taskW.outValues{taskW.totalRuns,1};
   		end
   		
   		%-----initialise eyelink and draw fix spaot
   		if useEyeLink == true
   			updateFixationValues(eL, fixX, fixY, firstFixInit, firstFixTime, firstFixRadius, strictFixation);
   			trackerClearScreen(eL);
   			trackerDrawFixation(eL); %draw fixation window on eyelink computer
   			%trackerDrawStimuli(eL,ts);
   			edfMessage(eL,['TRIALID ' num2str(loop)]); ...
    			startRecording(eL);
   			syncTime(eL);
   			statusMessage(eL,['t' ' FIX']);
   			WaitSecs(0.1);
   			fixated = '';
   			while ~strcmpi(fixated,'fix') && ~strcmpi(fixated,'breakfix')
   				drawSpot(s,0.1,[1 1 0],fixx,fixy);
   				Screen('DrawingFinished', s.win); %tell PTB/GPU to draw
   				Screen('Flip',s.win); %flip the buffer
   				getSample(eL);
   				fixated=testSearchHoldFixation(eL,'fix','breakfix');
   			end
   		else
   			drawSpot(s,0.1,[1 1 0],fixx,fixy);
   			Screen('Flip',s.win); %flip the buffer
   			WaitSecs(0.5);
   			fixated = 'fix';
   		end
   		
   		%------Our main stimulus drawing loop
   		while strcmpi(fixated,'fix') %initial fixation held
   			if useEyeLink == true;statusMessage(eL,'Show Stimulus...');end
   			stimuli.show();
   			drawSpot(s,0.1,[1 1 0],fixx,fixy);
   			vbl = Screen('Flip',s.win); %flip the buffer
   			vbls = vbl;
   			while GetSecs <= vbls+stimTime
   				draw(stimuli); %draw stimulus
   				drawSpot(s,0.1,[1 1 0],fixx,fixy);
   				Screen('DrawingFinished', s.win); %tell PTB/GPU to draw
   				if useEyeLink == true;
   					getSample(eL); %drawEyePosition(eL);
   					isfix = isFixated(eL);
   					if ~isfix
   						fixated = 'breakfix';
   						break;
   					end
   				end
   				animate(stimuli); %animate stimulus, will be seen on next draw
   				nextvbl = vbl + screenVals.halfisi;
   				vbl = Screen('Flip',s.win, nextvbl); %flip the buffer
   			end
   			if ~strcmpi(fixated,'fix')
   				statusMessage(eL,'BreakFix'); edfMessage(eL,'BreakFix')
   				continue
   			end
   			stimuli{1}.colourOut = pedestal;
   			stimuli.update();
   			vbls = vbl;
   			while GetSecs <= vbls+maskTime
   				draw(stimuli); %draw stimulus
   				drawSpot(s,0.1,[1 1 0],fixx,fixy);
   				Screen('DrawingFinished', s.win); %tell PTB/GPU to draw
   				if useEyeLink == true;
   					getSample(eL); %drawEyePosition(eL);
   					isfix = isFixated(eL);
   					if ~isfix
   						fixated = 'breakfix';
   						break;
   					end
   				end
   				%animate(stimuli); %animate stimulus, will be seen on next draw
   				nextvbl = vbl + screenVals.halfisi;
   				vbl = Screen('Flip',s.win, nextvbl); %flip the buffer
   			end
   			if ~strcmpi(fixated,'fix')
   				statusMessage(eL,'BreakFix'); edfMessage(eL,'BreakFix')
   				continue
   			end
   			stimuli.showMask = true; %metaStimulus can trigger a mask
   			vbls = vbl;
   			while GetSecs <= vbls+maskOffsetTime
   				draw(stimuli); %draw stimulus
   				drawSpot(s,0.1,[1 1 0],fixx,fixy);
       % 				if useEyeLink == true;
       % 					getSample(eL); %drawEyePosition(eL);
       % 				end
   				Screen('DrawingFinished', s.win); %tell PTB/GPU to draw
   				animate(stimuli); %animate stimulus, will be seen on next draw
   				nextvbl = vbl + screenVals.halfisi;
   				vbl = Screen('Flip',s.win, nextvbl); %flip the buffer
   			end
   			
   			drawBackground(s);
   			Screen('DrawText',s.win,['Did you see any stimulus after the initial spot & before the grating?'],0,0);
   			Screen('Flip',s.win);
   			
   			%-----check keyboard
   			timeout = GetSecs+5;
   			breakloopkey = false;
   			fixated = 'no';
   			ListenChar(2);
   			while ~breakloopkey
   				[keyIsDown, ~, keyCode] = KbCheck(-1);
   				if keyIsDown == 1
   					switch lower(KbName(keyCode))
   						case {'leftarrow','left'}
   							task.response.response(task.totalRuns) = 1;
   							task.response.N = task.totalRuns;
   							task.response.contrastOut(task.totalRuns) = colourOut;
   							task.response.pedestal(task.totalRuns) = pedestal;
   							task.response.blackN(task.totalRuns) = taskB.totalRuns;
   							task.response.whiteN(task.totalRuns) = taskW.totalRuns;
   							breakloopkey = true;
   							task.totalRuns = task.totalRuns + 1;
   							if colourOut == 0
   								taskB.totalRuns = taskB.totalRuns + 1;
   							else
   								taskW.totalRuns = taskW.totalRuns + 1;
   							end
   						case {'rightarrow','right'}
   							task.response.response(task.totalRuns) = 0;
   							task.response.N = task.totalRuns;
   							task.response.contrastOut(task.totalRuns) = colourOut;
   							task.response.pedestal(task.totalRuns) = pedestal;
   							task.response.blackN(task.totalRuns) = taskB.totalRuns;
   							task.response.whiteN(task.totalRuns) = taskW.totalRuns;
   							breakloopkey = true;
   							task.totalRuns = task.totalRuns + 1;
   							if colourOut == 0
   								taskB.totalRuns = taskB.totalRuns + 1;
   							else
   								taskW.totalRuns = taskW.totalRuns + 1;
   							end
   						case {'c'} %calibrate
   							stopRecording(eL);
   							setOffline(eL);
   							trackerSetup(eL);
   							
   						case {'escape','esc'} %quit
   							fprintf('\nQUIT!\n');
   							breakloop = true;
   							breakloopkey = true;
   							fixated = 'no';
   						otherwise
   					end
   				end
   				%if timeout<=GetSecs; breakloopkey = true; end
   			end
   			ListenChar(0);
   		end
   		resetFixation(eL);
   		stopRecording(eL);
   		setOffline(eL);
   		drawBackground(s);
   		Screen('Flip',s.win); %flip the buffer
   		WaitSecs(0.5);
   	end
   	%-----Cleanup
   	Screen('Flip',s.win);
   	Priority(0); ListenChar(0); ShowCursor;
   	close(s); %close screen
   	if useEyeLink == true; close(eL); end
   	p=uigetdir(pwd,'Select Directory to Save Data');
   	if isstr(p)
   		cd(p); save([name '.mat'], 'task', 'taskB', 'taskW');
   	end
   	reset(stimuli); %reset our stimulus ready for use again
    
   catch ME
   	ple(ME)
   	Priority(0); ListenChar(0); ShowCursor;
   	if useEyeLink == true; close(eL); end
   	reset(stimuli);
   	close(s); %close screen
   	clear stimuli task eL s
   	rethrow(ME);
   end
  end
  
 end
 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
        end

        % NumericEditField value changed function
        function changeMaskTime(app)
   value = app.NumericEditField.Value;
   
        end

        % Switch value changed function
        function toggleEyelink(app)
     value = app.Switch.Value;
     if strcmpi(value,'on')
      app.useEyeLink = true;
      app.Lamp.Color = [1 0 0];
     else
      app.useEyeLink = false;
      app.Lamp.Color = [0.5 0.5 0.5];
     end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Afterimage Contrast';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Settings';
            app.Panel.Units = 'pixels';
            app.LabelSlider = uilabel(app.Panel);
            app.LabelSlider.Text = 'Fix Window';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 5];
            app.Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.Slider.Value = 1.5;
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Mask Time';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @changeMaskTime);
            app.NumericEditField.Limits = [0 5];
            app.NumericEditField.Value = 0.3;
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Mask Time';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.Limits = [0 5];
            app.NumericEditField2.Value = 0.3;
            app.LabelSwitch = uilabel(app.Panel);
            app.LabelSwitch.Text = 'Eyelink?';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @toggleEyelink);
            app.Switch.Value = 'On';
            app.LabelLamp = uilabel(app.Panel);
            app.LabelLamp.Text = '?';
            app.Lamp = uilamp(app.Panel);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Psychophysical Results');
            xlabel(app.UIAxes, 'Trial');
            ylabel(app.UIAxes, 'Contrast');
        end
    end

    methods (Access = public)

        % Construct app
        function app = masked()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end