classdef maskedAI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskedAfterImageTestsUIFigure   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        CButton                         matlab.ui.control.Button
        SPButton                        matlab.ui.control.Button
        InfoEditFieldLabel              matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        CalibrationEditField            matlab.ui.control.EditField
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel       matlab.ui.control.Label
        PixelsPerCmEditField            matlab.ui.control.NumericEditField
        ELDummySwitchLabel              matlab.ui.control.Label
        ELDummySwitch                   matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel            matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.RockerSwitch
        EyelinkPanel                    matlab.ui.container.Panel
        SampleRateKnobLabel             matlab.ui.control.Label
        SampleRateKnob                  matlab.ui.control.DiscreteKnob
        CalibPointsKnobLabel            matlab.ui.control.Label
        CalibPointsKnob                 matlab.ui.control.DiscreteKnob
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        FixInitiationEditFieldLabel     matlab.ui.control.Label
        FixInitiationEditField          matlab.ui.control.NumericEditField
        FixationDiameterEditFieldLabel  matlab.ui.control.Label
        FixationDiameterEditField       matlab.ui.control.NumericEditField
        Fix1stDurationEditFieldLabel    matlab.ui.control.Label
        Fix1stDurationEditField         matlab.ui.control.NumericEditField
        BackgroundColorEditFieldLabel   matlab.ui.control.Label
        BackgroundColorEditField        matlab.ui.control.EditField
        TaskListBoxLabel                matlab.ui.control.Label
        TaskListBox                     matlab.ui.control.ListBox
        nBlocksEditFieldLabel           matlab.ui.control.Label
        nBlocksEditField                matlab.ui.control.NumericEditField
        EyelinkSwitchLabel              matlab.ui.control.Label
        EyelinkSwitch                   matlab.ui.control.RockerSwitch
        AdditionalOptionsPanel          matlab.ui.container.Panel
        StairCaseSwitchLabel            matlab.ui.control.Label
        StairCaseSwitch                 matlab.ui.control.Switch
        PedestaltEditFieldLabel         matlab.ui.control.Label
        PedestaltEditField              matlab.ui.control.NumericEditField
        StimulustEditFieldLabel         matlab.ui.control.Label
        StimulustEditField              matlab.ui.control.NumericEditField
        DiscSizeEditFieldLabel          matlab.ui.control.Label
        DiscSizeEditField               matlab.ui.control.NumericEditField
        MasktEditFieldLabel             matlab.ui.control.Label
        MasktEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        PFDropDownLabel                 matlab.ui.control.Label
        PFDropDown                      matlab.ui.control.DropDown
        gammaEditFieldLabel             matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        betamaxEditFieldLabel           matlab.ui.control.Label
        betamaxEditField                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        PedestalRangeEditFieldLabel     matlab.ui.control.Label
        PedestalRangeEditField          matlab.ui.control.EditField
        PriorsSwitchLabel               matlab.ui.control.Label
        PriorsSwitch                    matlab.ui.control.Switch
        LogalphaCheckBox                matlab.ui.control.CheckBox
        alphaSDEditFieldLabel           matlab.ui.control.Label
        alphaSDEditField                matlab.ui.control.NumericEditField
        alphapriorWEditFieldLabel       matlab.ui.control.Label
        alphapriorWEditField            matlab.ui.control.NumericEditField
        betaSDEditFieldLabel            matlab.ui.control.Label
        betaSDEditField                 matlab.ui.control.NumericEditField
        betapriorEditFieldLabel         matlab.ui.control.Label
        betapriorEditField              matlab.ui.control.NumericEditField
        marginaliseEditFieldLabel       matlab.ui.control.Label
        marginaliseEditField            matlab.ui.control.EditField
        TestButton                      matlab.ui.control.Button
        LogbetaCheckBox                 matlab.ui.control.CheckBox
        betagrainEditFieldLabel         matlab.ui.control.Label
        betagrainEditField              matlab.ui.control.NumericEditField
        alphagrainEditFieldLabel        matlab.ui.control.Label
        alphagrainEditField             matlab.ui.control.NumericEditField
        alphapriorBEditFieldLabel       matlab.ui.control.Label
        alphapriorBEditField            matlab.ui.control.NumericEditField
        StimulusConEditFieldLabel       matlab.ui.control.Label
        StimulusConEditField            matlab.ui.control.NumericEditField
        RunPanel                        matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        PlotsTab                        matlab.ui.container.Tab
        Data1Axes                       matlab.ui.control.UIAxes
        Data2Axes                       matlab.ui.control.UIAxes
        Data4Axes                       matlab.ui.control.UIAxes
        Data3Axes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ana % main settings
    end

 methods (Access = private)

  function parseSettings(app)
       app.ana = struct();
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
       app.ana.distance = app.DistanceEditField.Value;
       app.ana.nBlocks = app.nBlocksEditField.Value;
       app.ana.fixX = app.XEditField.Value;
       app.ana.fixY = app.YEditField.Value;
       app.ana.firstFixInit = app.FixInitiationEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       
       app.ana.pedestalTime = app.PedestaltEditField.Value;
       app.ana.stimulusTime = app.StimulustEditField.Value;
       app.ana.stimulusCon = app.StimulusConEditField.Value;
       app.ana.maskTime = app.MasktEditField.Value;
       app.ana.discSize = app.DiscSizeEditField.Value;
       app.ana.sigma = app.SigmaEditField.Value;
      
       app.ana.pedestalRange = str2num(app.PedestalRangeEditField.Value);
       app.ana.betaMax = app.betamaxEditField.Value;
       app.ana.gamma = app.gammaEditField.Value;
       app.ana.lambda = app.lambdaEditField.Value;
       val = str2num(app.marginaliseEditField.Value);
       if isempty(val)
           app.ana.marginalize = []; 
       else
           app.ana.marginalize = val;
       end
       app.ana.alphaPriorW = app.alphapriorWEditField.Value;
       app.ana.alphaPriorB = app.alphapriorBEditField.Value;
       app.ana.alphaPrior = app.ana.alphaPriorW;
       app.ana.alphaSD = app.alphaSDEditField.Value;
       app.ana.betaPrior = app.betapriorEditField.Value;
       app.ana.betaSD = app.betaSDEditField.Value;
       app.ana.alphaGrain = app.alphagrainEditField.Value;
       app.ana.betaGrain = app.betagrainEditField.Value;
      
       app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
       app.ana.calibrationStyle = app.CalibPointsKnob.Value;
       
       app.ana.PF = eval(['@PAL_' app.PFDropDown.Value]);
       app.ana.logTransform = logical(app.LogalphaCheckBox.Value);
       app.ana.logSlope = logical(app.LogbetaCheckBox.Value);
       
       app.ana.myFunc = ['masked' app.TaskListBox.Value '(app.ana)'];
       
       if strcmpi(app.StairCaseSwitch.Value,'On')
        app.ana.useStaircase = true;
       else
         app.ana.useStaircase = false;
       end
       if strcmpi(app.PriorsSwitch.Value,'On')
        app.ana.usePriors = true;
       else
        app.ana.usePriors = false;
       end
       
       if strcmpi(app.EyelinkSwitch.Value,'On')
        app.ana.useEyelink = true;
       else
        app.ana.useEyelink = false;
       end
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       app.ana.plotAxis1 = app.Data1Axes;
       app.ana.plotAxis2 = app.Data2Axes;
       app.ana.plotAxis3 = app.Data3Axes;
       app.ana.plotAxis4 = app.Data4Axes;
  end
  
 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SavePathEditField.Value = [getenv('HOME') '/Desktop/'];
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings()
           
           fprintf('\n=========RUN: %s============\n',app.ana.myFunc)
           eval(app.ana.myFunc)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             app.parseSettings();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
            app.SavePathEditField.Value = uigetdir;
        end

        % Value changed function: TaskListBox
        function TaskListBoxValueChanged(app, event)
            value = app.TaskListBox.Value;
            switch value
                case 'AIContrast'
                    app.PedestalRangeEditField.Value = '0 : 0.05 : 0.35';
                    app.PedestaltEditField.Value = 0.4;
                case 'ConThreshold'
                    app.PedestalRangeEditField.Value = '0 : 0.002 : 0.1';
                    app.PedestaltEditField.Value = 0;
                case 'AIConThreshold'
                    app.PedestalRangeEditField.Value = '0 : 0.02 : 0.4';
                    app.PedestaltEditField.Value = 0.4;
	            case 'AIPerceptualSize'
                    app.PedestalRangeEditField.Value = '0:0.02:0.2';
                    app.PedestaltEditField.Value = 0.4;
            end
                   
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            range = app.PedestalRangeEditField.Value;
            plotPF(range);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskedAfterImageTestsUIFigure = uifigure('Visible', 'off');
            app.MaskedAfterImageTestsUIFigure.Name = 'Masked AfterImage Tests';
            app.TabGroup = uitabgroup(app.MaskedAfterImageTestsUIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GeneralPanel = uipanel(app.ControlTab);
            app.GeneralPanel.Title = 'General';
            app.CButton = uibutton(app.GeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = '...';
            app.SPButton = uibutton(app.GeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Text = '...';
            app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
            app.InfoEditField.Value = 'AI Task';
            app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
            app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/';
            app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
            app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DistanceEditField.Limits = [20 200];
            app.DistanceEditField.Value = 62.5;
            app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Limits = [5 300];
            app.PixelsPerCmEditField.Value = 27;
            app.ELDummySwitchLabel = uilabel(app.GeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.GeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.EyelinkPanel = uipanel(app.GeneralPanel);
            app.EyelinkPanel.Title = 'Eyelink';
            app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
            app.SampleRateKnobLabel.Text = 'Sample Rate';
            app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
            app.SampleRateKnob.Value = '250';
            app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
            app.CalibPointsKnobLabel.Text = 'Calib Points';
            app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
            app.CalibPointsKnob.Value = 'HV5';
            app.XEditFieldLabel = uilabel(app.EyelinkPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EyelinkPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EyelinkPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EyelinkPanel, 'numeric');
            app.FixInitiationEditFieldLabel = uilabel(app.EyelinkPanel);
            app.FixInitiationEditFieldLabel.Text = 'Fix Initiation';
            app.FixInitiationEditField = uieditfield(app.EyelinkPanel, 'numeric');
            app.FixInitiationEditField.Value = 1;
            app.FixationDiameterEditFieldLabel = uilabel(app.EyelinkPanel);
            app.FixationDiameterEditFieldLabel.Text = 'Fixation Diameter';
            app.FixationDiameterEditField = uieditfield(app.EyelinkPanel, 'numeric');
            app.FixationDiameterEditField.Limits = [0.1 20];
            app.FixationDiameterEditField.Value = 2;
            app.Fix1stDurationEditFieldLabel = uilabel(app.EyelinkPanel);
            app.Fix1stDurationEditFieldLabel.Text = 'Fix 1st Duration';
            app.Fix1stDurationEditField = uieditfield(app.EyelinkPanel, 'numeric');
            app.Fix1stDurationEditField.Limits = [0.1 20];
            app.Fix1stDurationEditField.Value = 0.5;
            app.BackgroundColorEditFieldLabel = uilabel(app.GeneralPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.GeneralPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
            app.TaskListBoxLabel = uilabel(app.GeneralPanel);
            app.TaskListBoxLabel.Text = 'Task';
            app.TaskListBox = uilistbox(app.GeneralPanel);
            app.TaskListBox.Items = {'AIContrast', 'AILatency2', 'AIPerceptualSize', 'AIDuration', 'AIConThreshold', 'AISize', 'AISize2', 'Latency', 'AILatency', 'Latency2stColor'};
            app.TaskListBox.ValueChangedFcn = createCallbackFcn(app, @TaskListBoxValueChanged, true);
            app.TaskListBox.Value = 'AIContrast';
            app.nBlocksEditFieldLabel = uilabel(app.GeneralPanel);
            app.nBlocksEditFieldLabel.Text = 'nBlocks';
            app.nBlocksEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.nBlocksEditField.Limits = [1 2000];
            app.nBlocksEditField.Value = 4;
            app.EyelinkSwitchLabel = uilabel(app.GeneralPanel);
            app.EyelinkSwitchLabel.Text = 'Eyelink?';
            app.EyelinkSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.EyelinkSwitch.Value = 'On';
            app.AdditionalOptionsPanel = uipanel(app.ControlTab);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.StairCaseSwitchLabel = uilabel(app.AdditionalOptionsPanel);
            app.StairCaseSwitchLabel.Text = 'StairCase?';
            app.StairCaseSwitch = uiswitch(app.AdditionalOptionsPanel, 'slider');
            app.StairCaseSwitch.Value = 'On';
            app.PedestaltEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.PedestaltEditFieldLabel.Text = 'Pedestal t';
            app.PedestaltEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.PedestaltEditField.Limits = [0 300];
            app.PedestaltEditField.Value = 0.4;
            app.StimulustEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.StimulustEditFieldLabel.Text = 'Stimulus t';
            app.StimulustEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.StimulustEditField.Limits = [0 200];
            app.StimulustEditField.Value = 4;
            app.DiscSizeEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.DiscSizeEditFieldLabel.Text = 'Disc Size';
            app.DiscSizeEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.DiscSizeEditField.Limits = [0 300];
            app.DiscSizeEditField.Value = 2;
            app.MasktEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.MasktEditFieldLabel.Text = 'Mask t';
            app.MasktEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.MasktEditField.Limits = [0 200];
            app.MasktEditField.Value = 1.5;
            app.SigmaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.SigmaEditField.Limits = [0 300];
            app.SigmaEditField.Value = 9;
            app.PFDropDownLabel = uilabel(app.AdditionalOptionsPanel);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.AdditionalOptionsPanel);
            app.PFDropDown.Items = {'Weibull', 'Quick', 'Gumbel', 'logQuick', 'Logistic', 'CumulativeNormal', 'HyperbolicSecant'};
            app.PFDropDown.Editable = 'on';
            app.PFDropDown.Value = 'Quick';
            app.gammaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.gammaEditField.Limits = [0 300];
            app.betamaxEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betamaxEditFieldLabel.Text = 'beta max';
            app.betamaxEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betamaxEditField.Limits = [0 2000];
            app.betamaxEditField.Value = 30;
            app.lambdaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.lambdaEditField.Limits = [0 200];
            app.lambdaEditField.Value = 0.01;
            app.PedestalRangeEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.PedestalRangeEditFieldLabel.Text = 'Pedestal Range';
            app.PedestalRangeEditField = uieditfield(app.AdditionalOptionsPanel, 'text');
            app.PedestalRangeEditField.Value = '0 : 0.05 : 0.35';
            app.PriorsSwitchLabel = uilabel(app.AdditionalOptionsPanel);
            app.PriorsSwitchLabel.Text = 'Priors?';
            app.PriorsSwitch = uiswitch(app.AdditionalOptionsPanel, 'slider');
            app.PriorsSwitch.Value = 'On';
            app.LogalphaCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.LogalphaCheckBox.Text = 'Log alpha';
            app.alphaSDEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphaSDEditFieldLabel.Text = 'alpha SD';
            app.alphaSDEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphaSDEditField.Limits = [0 300];
            app.alphaSDEditField.Value = 0.2;
            app.alphapriorWEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphapriorWEditFieldLabel.Text = 'alpha priorW';
            app.alphapriorWEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphapriorWEditField.Limits = [0 2000];
            app.alphapriorWEditField.Value = 0.4;
            app.betaSDEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betaSDEditFieldLabel.Text = 'beta SD';
            app.betaSDEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betaSDEditField.Limits = [0 300];
            app.betaSDEditField.Value = 5;
            app.betapriorEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betapriorEditFieldLabel.Text = 'beta prior';
            app.betapriorEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betapriorEditField.Limits = [0 200];
            app.betapriorEditField.Value = 15;
            app.marginaliseEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.marginaliseEditFieldLabel.Text = 'marginalise';
            app.marginaliseEditField = uieditfield(app.AdditionalOptionsPanel, 'text');
            app.marginaliseEditField.Value = '''slope''';
            app.TestButton = uibutton(app.AdditionalOptionsPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.LogbetaCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.LogbetaCheckBox.Text = 'Log beta';
            app.LogbetaCheckBox.Value = true;
            app.betagrainEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betagrainEditFieldLabel.Text = 'beta grain';
            app.betagrainEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betagrainEditField.Limits = [0 1000];
            app.betagrainEditField.Value = 50;
            app.alphagrainEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphagrainEditFieldLabel.Text = 'alpha grain';
            app.alphagrainEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphagrainEditField.Limits = [0 1000];
            app.alphagrainEditField.Value = 200;
            app.alphapriorBEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphapriorBEditFieldLabel.Text = 'alpha priorB';
            app.alphapriorBEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphapriorBEditField.Limits = [0 2000];
            app.alphapriorBEditField.Value = 0.4;
            app.StimulusConEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.StimulusConEditFieldLabel.Text = 'Stimulus con';
            app.StimulusConEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.StimulusConEditField.Limits = [0 200];
            app.StimulusConEditField.Value = 0.5;
            app.RunPanel = uipanel(app.ControlTab);
            app.RunPanel.Title = 'Run';
            app.GOButton = uibutton(app.RunPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.RunPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.RunPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit!';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.Data1Axes = uiaxes(app.PlotsTab);
            title(app.Data1Axes, 'Raw Data')
            xlabel(app.Data1Axes, 'Trial')
            ylabel(app.Data1Axes, 'Stimulus Value')
            app.Data2Axes = uiaxes(app.PlotsTab);
            title(app.Data2Axes, 'PF')
            xlabel(app.Data2Axes, 'Stimulus Value')
            ylabel(app.Data2Axes, 'Choice Probability')
            app.Data4Axes = uiaxes(app.PlotsTab);
            title(app.Data4Axes, 'Posterior')
            xlabel(app.Data4Axes, 'beta')
            ylabel(app.Data4Axes, 'alpha')
            app.Data3Axes = uiaxes(app.PlotsTab);
            title(app.Data3Axes, 'Posterior')
            xlabel(app.Data3Axes, 'beta')
            ylabel(app.Data3Axes, 'alpha')
            % Show the figure after all components are created
            app.MaskedAfterImageTestsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maskedAI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskedAfterImageTestsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskedAfterImageTestsUIFigure)
        end
    end
end