classdef plotPF < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		PfTesterUIFigure      matlab.ui.Figure
		ValuesPanel           matlab.ui.container.Panel
		LogBetaCheckBox       matlab.ui.control.CheckBox
		lambdaEditField       matlab.ui.control.NumericEditField
		lambdaEditFieldLabel  matlab.ui.control.Label
		gammaEditField        matlab.ui.control.NumericEditField
		gammaEditFieldLabel   matlab.ui.control.Label
		betaEditField         matlab.ui.control.NumericEditField
		betaEditFieldLabel    matlab.ui.control.Label
		alphaEditField        matlab.ui.control.NumericEditField
		alphaEditFieldLabel   matlab.ui.control.Label
		LogAlphaCheckBox      matlab.ui.control.CheckBox
		RangeEditField        matlab.ui.control.EditField
		RangeEditFieldLabel   matlab.ui.control.Label
		PFDropDown            matlab.ui.control.DropDown
		PFDropDownLabel       matlab.ui.control.Label
		UIAxes                matlab.ui.control.UIAxes
	end


	methods (Access = private)


	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, range)
			if exist('range','var') && ~isempty(range)
				if isstruct(range)
					app.alphaEditField.Value = range.alpha;
					app.betaEditField.Value = range.beta;
					app.gammaEditField.Value = range.gamma;
					app.lambdaEditField.Value = range.lambda;
					app.RangeEditField.Value = range.values;
				else
					app.RangeEditField.Value = range;
				end
			end
			RangeEditFieldValueChanged(app)
		end

		% Value changed function: LogAlphaCheckBox, 
		% LogBetaCheckBox, PFDropDown, RangeEditField, 
		% alphaEditField, betaEditField, gammaEditField, 
		% lambdaEditField
		function RangeEditFieldValueChanged(app, event)
			values = str2num(app.RangeEditField.Value);
			PF = eval(['@PAL_' app.PFDropDown.Value]);
			logT = logical(app.LogAlphaCheckBox.Value);
			logS = logical(app.LogBetaCheckBox.Value);
			alpha = app.alphaEditField.Value;
			beta = app.betaEditField.Value;
			gamma = app.gammaEditField.Value;
			lambda = app.lambdaEditField.Value;

			if logT
				values = log10(values);
				alpha = log10(alpha);
			end

			if logS
				beta = log10(beta);
			end

			try
				pf = PF([alpha beta gamma lambda],values);
				if logT
					values = 10.^values;
				end
				plot(app.UIAxes,values,pf,'LineWidth',2);
				app.UIAxes.XMinorGrid = 'on';
				app.UIAxes.YMinorGrid = 'on';
				if logT
					app.UIAxes.XScale = 'log';
				else
					app.UIAxes.XScale = 'linear';
				end
				%                 if logS
				%                     app.UIAxes.YScale = 'log';
				%                 else
				%                     app.UIAxes.YScale = 'linear';
				%                 end
				ylim(app.UIAxes,[gamma-0.1 1.1]);
				xlabel(app.UIAxes,'Stimulus Value')
				ylabel(app.UIAxes,'Proportion Correct')
				title(app.UIAxes,'Psychometric Function')
			catch
				cla(app.UIAxes)
				title(app.UIAxes,'Error in generating PF')
			end

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.PfTesterUIFigure = uifigure('Visible', 'off');
			app.PfTesterUIFigure.Name = 'Pf Tester';
			app.UIAxes = uiaxes(app.PfTesterUIFigure);
			title(app.UIAxes, 'Psychometric Function')
			xlabel(app.UIAxes, 'Stimulus Value')
			ylabel(app.UIAxes, 'Proportion Correct')
			app.ValuesPanel = uipanel(app.PfTesterUIFigure);
			app.ValuesPanel.Title = 'Values';
			app.PFDropDownLabel = uilabel(app.ValuesPanel);
			app.PFDropDownLabel.Text = 'PF';
			app.PFDropDown = uidropdown(app.ValuesPanel);
			app.PFDropDown.Items = {'Weibull', 'Quick', 'Gumbel', 'logQuick', 'Logistic', 'CumulativeNormal', 'HyperbolicSecant'};
			app.PFDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.PFDropDown.Value = 'Quick';
			app.RangeEditFieldLabel = uilabel(app.ValuesPanel);
			app.RangeEditFieldLabel.Text = 'Range';
			app.RangeEditField = uieditfield(app.ValuesPanel, 'text');
			app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.RangeEditField.Value = '0 : 0.02 : 1';
			app.LogAlphaCheckBox = uicheckbox(app.ValuesPanel);
			app.LogAlphaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.LogAlphaCheckBox.Text = 'Log Alpha?';
			app.alphaEditFieldLabel = uilabel(app.ValuesPanel);
			app.alphaEditFieldLabel.Text = 'alpha';
			app.alphaEditField = uieditfield(app.ValuesPanel, 'numeric');
			app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.alphaEditField.Value = 0.5;
			app.betaEditFieldLabel = uilabel(app.ValuesPanel);
			app.betaEditFieldLabel.Text = 'beta';
			app.betaEditField = uieditfield(app.ValuesPanel, 'numeric');
			app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.betaEditField.Value = 5;
			app.gammaEditFieldLabel = uilabel(app.ValuesPanel);
			app.gammaEditFieldLabel.Text = 'gamma';
			app.gammaEditField = uieditfield(app.ValuesPanel, 'numeric');
			app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.gammaEditField.Value = 0.5;
			app.lambdaEditFieldLabel = uilabel(app.ValuesPanel);
			app.lambdaEditFieldLabel.Text = 'lambda';
			app.lambdaEditField = uieditfield(app.ValuesPanel, 'numeric');
			app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.lambdaEditField.Value = 0.01;
			app.LogBetaCheckBox = uicheckbox(app.ValuesPanel);
			app.LogBetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
			app.LogBetaCheckBox.Text = 'Log Beta?';
			% Show the figure after all components are created
			app.PfTesterUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = plotPF(varargin)

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.PfTesterUIFigure)

			% Execute the startup function
			runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.PfTesterUIFigure)
		end
	end
end